classdef Ribbles < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RibblesUIFigure                 matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        GridLayout9                     matlab.ui.container.GridLayout
        Image                           matlab.ui.control.Image
        Tree                            matlab.ui.container.CheckBoxTree
        RibcageNode                     matlab.ui.container.TreeNode
        SplittedRibcageNode             matlab.ui.container.TreeNode
        SpineNode                       matlab.ui.container.TreeNode
        SpinecenterlineNode             matlab.ui.container.TreeNode
        RibsNode                        matlab.ui.container.TreeNode
        SegmentedribsNode               matlab.ui.container.TreeNode
        RibssegmentedNode               matlab.ui.container.TreeNode
        RibssegmentedcenterlinesNode    matlab.ui.container.TreeNode
        FoundribpairsNode               matlab.ui.container.TreeNode
        RibpairsNode                    matlab.ui.container.TreeNode
        RibpairscenterlinesNode         matlab.ui.container.TreeNode
        DeformationRibcageNode          matlab.ui.container.TreeNode
        RibsdeformationNode             matlab.ui.container.TreeNode
        RibsdeformationcenterlinesNode  matlab.ui.container.TreeNode
        UIAxes                          matlab.ui.control.UIAxes
        PreOperativeLabel               matlab.ui.control.Label
        StartAlgorithmButton            matlab.ui.control.Button
        GridLayout7                     matlab.ui.container.GridLayout
        SaveImageButton                 matlab.ui.control.Button
        LoadImageButton                 matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        GridLayout9_2                   matlab.ui.container.GridLayout
        Image_2                         matlab.ui.control.Image
        Tree_2                          matlab.ui.container.CheckBoxTree
        RibcageNode_2                   matlab.ui.container.TreeNode
        SplittedRibcageNode_2           matlab.ui.container.TreeNode
        SpineNode_2                     matlab.ui.container.TreeNode
        SpinecenterlineNode_2           matlab.ui.container.TreeNode
        RibsNode_2                      matlab.ui.container.TreeNode
        SegmentedribsNode_2             matlab.ui.container.TreeNode
        RibssegmentedNode_2             matlab.ui.container.TreeNode
        RibssegmentedcenterlinesNode_2  matlab.ui.container.TreeNode
        FoundribpairsNode_2             matlab.ui.container.TreeNode
        RibpairsNode_2                  matlab.ui.container.TreeNode
        RibpairscenterlinesNode_2       matlab.ui.container.TreeNode
        DeformationRibcageNode_2        matlab.ui.container.TreeNode
        RibsdeformationNode_2           matlab.ui.container.TreeNode
        RibsdeformationcenterlinesNode_2  matlab.ui.container.TreeNode
        UIAxes_2                        matlab.ui.control.UIAxes
        GridLayout8                     matlab.ui.container.GridLayout
        SaveImageButton_2               matlab.ui.control.Button
        LoadImageButton_2               matlab.ui.control.Button
        StartAlgorithmButton_2          matlab.ui.control.Button
        PostOperativeLabel              matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        colorribcage=[255,255,255]
        colorspine=[255,255,255]
        colorribs=[0,0,255]
              
        fullpathinput='C:\'
        pcribcage=0       
        ribcage=0     
        pcspinecenterline=0
        pcspine=0
        pcribs=0
        ribs=0
        pcindividual_ribs=0
        pcindividual_ribs_centerlines=[]
        pcindividual_ribs_centerlinesplot = 0
        pcrib_pairs=0
        pcrib_pairs_centerlines=[]
        pcrib_pairs_centerlinesplot=0
        pcdeformation_ribs=0
        pcdeformation_ribs_centerlines=[]
        pcdeformation_ribs_centerlinesplot=0
        plotted = containers.Map()
        done=0
    
        fullpathinput2='C:\'
        pcribcage2=0       
        ribcage2=0     
        pcspinecenterline2=0
        pcspine2=0
        pcribs2=0
        ribs2=0
        pcindividual_ribs2=0
        pcindividual_ribs_centerlines2=[]
        pcindividual_ribs_centerlinesplot2=0
        pcrib_pairs2=0
        pcrib_pairs_centerlines2=[]
        pcrib_pairs_centerlinesplot2=0
        pcdeformation_ribs2=0
        pcdeformation_ribs_centerlines2=[]
        pcdeformation_ribs_centerlinesplot2=0
        plotted2 = containers.Map()
        done2=0
        
        %%

    end
    
    methods (Access = private)      
        function myUpdateAppLayout(app, event)
            currentFigureWidth = app.RibblesUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {'1x', currentFigureWidth/2};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RibblesUIFigure.SizeChangedFcn = createCallbackFcn(app, @myUpdateAppLayout, true);
            app.UIAxes.Visible = 'off';
            app.UIAxes_2.Visible = 'off';
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [file, path] = uigetfile('*.nii', 'Select a file');
            delete(f);
            if ~isequal(file, 0)
                d = uiprogressdlg(app.RibblesUIFigure,'Title','Loading image...', 'Indeterminate','on');
                drawnow
                app.fullpathinput = string(fullfile(path, file));
                app.LoadImageButton.Text = file;

                axes = app.UIAxes;
                delete(axes.Children);
                app.plotted = containers.Map();
                app.pcspinecenterline=0;
                app.pcspine=0;
                app.pcribs=0;
                app.ribs=0;
                app.pcindividual_ribs=0;
                app.pcindividual_ribs_centerlines=[];
                app.pcindividual_ribs_centerlinesplot=0;
                app.pcrib_pairs=0;
                app.pcrib_pairs_centerlines=[];
                app.pcrib_pairs_centerlinesplot=0;
                app.pcdeformation_ribs=0;
                app.pcdeformation_ribs_centerlines=[];
                app.pcdeformation_ribs_centerlinesplot=0;

                [app.pcribcage, app.ribcage] = get_ribcage(app.fullpathinput, 5, 3, 1300, 1600, app.colorribcage);
                
                pcshow(app.pcribcage, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                app.Image.Visible = 'off';
                app.UIAxes.Visible = 'on';
                app.plotted("ribcage") = [axes.Children(1)];
                app.Tree.CheckedNodes = [app.RibcageNode];
                
                close(d);
            end
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            if app.done == 1
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                [file, path] = uitgetdir(app.fullpathinput, 'Select a folder');
                delete(f);
                if isstring(path)
                    app.SaveImageButton.Text = path;
                        d = uiprogressdlg(app.RibblesUIFigure,'Title','Saving images...', 'Indeterminate','on');
                        drawnow
                        pcwrite(app.pcribage, path + 'ribcage.ply');
                        pcwrite(app.pcribs, path+ 'ribs.ply');
                        pcwrite(app.pcspine, path+ 'spine.ply');
                        for i = 1:length(app.pcindividual_ribs_centerlines)
                            pcwrite(app.pcindividual_ribs_centerlines{i}, path+'rib_'+string(i)+'.ply');
                        end
                        for i = 1:length(app.pcrib_pairs_centerlines)
                            pcwrite(app.pcrib_pairs_centerlines{i,1}, path+'rib_'+string(i)+'_1.ply');
                            pcwrite(app.pcrib_pairs_centerlines{i,2}, path+'rib_'+string(i)+'_2.ply');
                        end
                        pcwrite(app.pcdeformation_ribs, path + 'deformation_ribs.ply');
                        for i = 1:length(app.pcdeformation_ribs_centerlines)
                            pcwrite(app.pcdeformation_ribs_centerlines{i,1}, path+'deformation_rib_'+string(i)+'_1.ply');
                            pcwrite(app.pcdeformation_ribs_centerlines{i,2}, path+'deformation_rib_'+string(i)+'_2.ply');
                        end
                        close(d);
                else
                    uialert(app.RibblesUIFigure, 'Insert valid folder', 'Warning')
                end
            else
                uialert(app.RibblesUIFigure, 'Not done processing', 'Warning'); 
            end
        end

        % Button pushed function: StartAlgorithmButton
        function StartAlgorithmButtonPushed(app, event)
            if isstring(app.fullpathinput)
                d = uiprogressdlg(app.RibblesUIFigure,'Title','Seperating ribcage...', 'Indeterminate','on');
                drawnow
                [app.pcspinecenterline, app.pcspine, app.pcribs, app.ribs]=seperate_ribcage(app.ribcage, app.colorspine, app.colorribs);
                axes=app.UIAxes;
                delete(axes.Children);
                app.plotted = containers.Map();
                hold(axes);
                pcshow(app.pcspine, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]); 
                app.plotted("spine") = [axes.Children(1)];
                pcshow(app.pcribs, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                app.plotted("ribs") = [axes.Children(1)];
                pcshow(app.pcspinecenterline, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                app.plotted("spinecenterline") = [axes.Children(1)];
                hold(axes);   
                app.Tree.CheckedNodes = [app.SpineNode, app.RibsNode, app.SpinecenterlineNode];
                close(d);

                d = uiprogressdlg(app.RibblesUIFigure,'Title','Seperating ribs...', 'Indeterminate','on');
                drawnow
                [app.pcindividual_ribs, app.pcindividual_ribs_centerlines]=seperate_ribs(app.ribs, app.pcspinecenterline, app.pcribs);   
                delete(axes.Children);
                app.plotted = containers.Map();
                hold(axes);
                pcshow(app.pcspine, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                app.plotted("spine") = [axes.Children(1)];
                pcshow(app.pcspinecenterline, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                app.plotted("spinecenterline")=[axes.Children(1)];
                pcshow(app.pcindividual_ribs, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                app.plotted("individual_ribs") = [axes.Children(1)];
                app.plotted("individual_ribs_centerlines") = [];
                app.pcindividual_ribs_centerlinesplot = pointCloud([0 0 0], 'Color', [0 0 0]);
                for i =1:length(app.pcindividual_ribs_centerlines)
                    app.pcindividual_ribs_centerlinesplot = pcmerge(app.pcindividual_ribs_centerlinesplot, app.pcindividual_ribs_centerlines{i}, 1);
                end
                app.pcindividual_ribs_centerlinesplot = pointCloud(app.pcindividual_ribs_centerlinesplot.Location(1:end, :), 'Color', app.pcindividual_ribs_centerlinesplot.Color(1:end, :));
                pcshow(app.pcindividual_ribs_centerlinesplot, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                app.plotted("individual_ribs_centerlines") = [axes.Children(1)];
                hold(axes)
                app.Tree.CheckedNodes = [app.SpineNode, app.RibssegmentedNode, app.RibssegmentedcenterlinesNode, app.SpinecenterlineNode];
                close(d);
                               
                d = uiprogressdlg(app.RibblesUIFigure,'Title','Finding rib pairs...', 'Indeterminate','on');
                drawnow
                [app.pcrib_pairs, app.pcrib_pairs_centerlines] = find_rib_pairs(app.pcindividual_ribs_centerlines, app.pcspinecenterline, app.pcribs);  
                delete(axes.Children);
                app.plotted=containers.Map();
                hold(axes);
                pcshow(app.pcspine, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                app.plotted("spine") = [axes.Children(1)];
                pcshow(app.pcspinecenterline, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                app.plotted("spinecenterline")=[axes.Children(1)];
                pcshow(app.pcrib_pairs, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                app.plotted("rib_pairs") = [axes.Children(1)];
                app.plotted("rib_pairs_centerlines") = [];
                app.pcrib_pairs_centerlinesplot = pointCloud([0 0 0], 'Color', [0 0 0]);
                for i =1:length(app.pcrib_pairs_centerlines)
                    app.pcrib_pairs_centerlinesplot = pcmerge(app.pcrib_pairs_centerlinesplot, app.pcrib_pairs_centerlines{i, 1}, 1);
                    app.pcrib_pairs_centerlinesplot = pcmerge(app.pcrib_pairs_centerlinesplot, app.pcrib_pairs_centerlines{i, 2}, 1);
                end
                app.pcrib_pairs_centerlinesplot = pointCloud(app.pcrib_pairs_centerlinesplot.Location(1:end, :), 'Color', app.pcrib_pairs_centerlinesplot.Color(1:end, :));
                pcshow(app.pcrib_pairs_centerlinesplot, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                app.plotted("rib_pairs_centerlines") = [axes.Children(1)];
                hold(axes)
                app.Tree.CheckedNodes = [app.SpineNode, app.RibpairsNode, app.RibpairscenterlinesNode, app.SpinecenterlineNode];
                close(d);

                d = uiprogressdlg(app.RibblesUIFigure,'Title','Calculating deformity of ribs...', 'Indeterminate','on');
                drawnow
                [app.pcdeformation_ribs, app.pcdeformation_ribs_centerlines] = calculate_deformity(app.pcrib_pairs_centerlines, app.pcribs);
                delete(axes.Children);
                app.plotted=containers.Map();
                hold(axes);
                pcshow(app.pcspine, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                app.plotted("spine") = [axes.Children(1)];
                pcshow(app.pcdeformation_ribs, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                app.plotted("deformation_ribs") = [axes.Children(1)];
                app.pcdeformation_ribs_centerlinesplot = pointCloud([0 0 0], 'Color', [0 0 0]);
                for i =1:length(app.pcdeformation_ribs_centerlines)
                    app.pcdeformation_ribs_centerlinesplot = pcmerge(app.pcdeformation_ribs_centerlinesplot, app.pcdeformation_ribs_centerlines{i, 1}, 1);
                    app.pcdeformation_ribs_centerlinesplot = pcmerge(app.pcdeformation_ribs_centerlinesplot, app.pcdeformation_ribs_centerlines{i, 2}, 1);
                end
                app.pcdeformation_ribs_centerlinesplot = pointCloud(app.pcdeformation_ribs_centerlinesplot.Location(1:end, :), 'Color', app.pcdeformation_ribs_centerlinesplot.Color(1:end, :));
                pcshow(app.pcdeformation_ribs_centerlinesplot, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                app.plotted("deformation_ribs_centerlines") = [axes.Children(1)];
                hold(axes)
                app.Tree.CheckedNodes = [app.SpineNode, app.DeformationRibcageNode, app.RibsdeformationNode, app.RibsdeformationcenterlinesNode];
                close(d);
                
                app.done = 1;
            else
                uialert(app.RibblesUIFigure, 'First load an image!','Warning');
            end
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            node = app.Tree.SelectedNodes;
            prevnodes = event.PreviousCheckedNodes;
            nodes = [];
            nodes_turned_on = [];
            axes = app.UIAxes;

            if isempty(node.Children)
                nodes = [nodes node];
            else
                for i=1:length(node.Children)
                    nodes = [nodes node.Children(i)];
                end
            end

            for i=1:length(nodes)
                nodes_turned_on = [nodes_turned_on 0];
                if ~isempty(prevnodes)
                    turned_on = true;
                    for j=1:length(prevnodes)
                        if strcmp(prevnodes(j).Text, nodes(i).Text)
                            turned_on = false;
                            break
                        end
                    end
                    nodes_turned_on(i) = turned_on;
                else
                    if nodes_turned_on(i) == 0
                        nodes_turned_on(i) = true;
                    end
                end
            end
            
            for i=1:length(nodes)
                switch nodes(i).Text
                    case "Ribcage"
                        if nodes_turned_on(i)
                            if app.pcribcage ~=0
                                d = uiprogressdlg(app.RibblesUIFigure,'Title','Plotting image...', 'Indeterminate','on');
                                drawnow

                                hold(axes)
                                pcshow(app.pcribcage, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                                app.plotted("ribcage") = [axes.Children(1)];
                                hold(axes);    
                                
                                close(d)
                            else
                                app.Tree.CheckedNodes = prevnodes;
                                uialert(app.RibblesUIFigure, 'Data not available!', 'Warning');
                            end
                        else
                            if isKey(app.plotted, "ribcage")
                                if ~isempty(app.plotted("ribcage"))
                                    d = uiprogressdlg(app.RibblesUIFigure,'Title','Removing image...', 'Indeterminate','on');
                                    drawnow
                                    
                                    instances = app.plotted("ribcage");
                                    for j =1:length(instances)
                                        delete(instances(j));
                                    end
                                    app.plotted("ribcage") = [];
    
                                    close(d)
                                end
                            end
                        end

                    case "Spine"
                        if nodes_turned_on(i)
                            if app.pcspine ~=0
                                d = uiprogressdlg(app.RibblesUIFigure,'Title','Plotting image...', 'Indeterminate','on');
                                drawnow

                                hold(axes);
                                pcshow(app.pcspine, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0])
                                app.plotted("spine") = axes.Children(1); 
                                hold(axes);
                                
                                close(d);
                            else
                                app.Tree.CheckedNodes = prevnodes;
                                uialert(app.RibblesUIFigure, 'Data not available!', 'Warning');
                            end
                        else
                            if isKey(app.plotted, "spine")
                                if ~isempty(app.plotted("spine"))
                                    d = uiprogressdlg(app.RibblesUIFigure,'Title','Removing image...', 'Indeterminate','on');
                                    drawnow
                                    
                                    instances = app.plotted("spine");
                                    for j =1:length(instances)
                                        delete(instances(j));
                                    end
                                    app.plotted("spine") = [];
    
                                    close(d)
                                end
                            end
                        end

                    case "Spine centerline"
                        if nodes_turned_on(i)
                            if app.pcspinecenterline ~=0
                                d = uiprogressdlg(app.RibblesUIFigure,'Title','Plotting image...', 'Indeterminate','on');
                                drawnow
                                
                                hold(axes);
                                pcshow(app.pcspinecenterline, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0])
                                app.plotted("spinecenterline") = axes.Children(1); 
                                hold(axes);

                                close(d);
                            else
                                app.Tree.CheckedNodes = prevnodes;
                                uialert(app.RibblesUIFigure, 'Data not available!', 'Warning');
                            end
                        else
                            if isKey(app.plotted, "spinecenterline")
                                if ~isempty(app.plotted("spinecenterline"))
                                    d = uiprogressdlg(app.RibblesUIFigure,'Title','Removing image...', 'Indeterminate','on');
                                    drawnow
                                    
                                    instances = app.plotted("spinecenterline");
                                    for j =1:length(instances)
                                        delete(instances(j));
                                    end
                                    app.plotted("spinecenterline") = [];
    
                                    close(d);
                                end
                            end
                        end

                    case "Ribs"
                        if nodes_turned_on(i)
                            if app.pcribs ~=0
                                d = uiprogressdlg(app.RibblesUIFigure,'Title','Plotting image...', 'Indeterminate','on');
                                drawnow
                                
                                hold(axes);
                                pcshow(app.pcribs, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0])
                                app.plotted("ribs") = axes.Children(1);
                                hold(axes);

                                close(d)
                            else
                                app.Tree.CheckedNodes = prevnodes;
                                uialert(app.RibblesUIFigure, 'Data not available!', 'Warning');
                            end
                        else
                            if isKey(app.plotted, "ribs")
                                if ~isempty(app.plotted("ribs"))
                                    d = uiprogressdlg(app.RibblesUIFigure,'Title','Removing image...', 'Indeterminate','on');
                                    drawnow
                                    
                                    instances = app.plotted("ribs");
                                    for j =1:length(instances)
                                        delete(instances(j));
                                    end
                                    app.plotted("ribs") = [];
    
                                    close(d);
                                end
                            end
                        end
                     
                    case "Ribs segmented"
                        if nodes_turned_on(i)
                            if app.pcindividual_ribs ~=0
                                d = uiprogressdlg(app.RibblesUIFigure,'Title','Plotting image...', 'Indeterminate','on');
                                drawnow
                                
                                hold(axes);
                                pcshow(app.pcindividual_ribs, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                                app.plotted("individual_ribs") = [axes.Children(1)];
                                hold(axes)

                                close(d)
                            else
                                app.Tree.CheckedNodes = prevnodes;
                                uialert(app.RibblesUIFigure, 'Data not available!', 'Warning');
                            end
                        else
                            if isKey(app.plotted, "individual_ribs")
                                if ~isempty(app.plotted("individual_ribs"))
                                    d = uiprogressdlg(app.RibblesUIFigure,'Title','Removing image...', 'Indeterminate','on');
                                    drawnow
                                    
                                    instances = app.plotted("individual_ribs");
                                    for j =1:length(instances)
                                        delete(instances(j));
                                    end
                                    app.plotted("individual_ribs") = [];
    
                                    close(d);
                                end
                            end
                        end
                    
                    case "Ribs segmented centerlines"
                        if nodes_turned_on(i)
                            if app.pcindividual_ribs_centerlinesplot~=0
                                d = uiprogressdlg(app.RibblesUIFigure,'Title','Plotting image...', 'Indeterminate','on');
                                drawnow
                                
                                hold(axes);
                                pcshow(app.pcindividual_ribs_centerlinesplot, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                                app.plotted("individual_ribs_centerlines") = [axes.Children(1)];
                                hold(axes)

                                close(d)
                            else
                                app.Tree.CheckedNodes = prevnodes;
                                uialert(app.RibblesUIFigure, 'Data not available!', 'Warning');
                            end
                        else
                            if isKey(app.plotted, "individual_ribs_centerlines")
                                if ~isempty(app.plotted("individual_ribs_centerlines"))
                                    d = uiprogressdlg(app.RibblesUIFigure,'Title','Removing image...', 'Indeterminate','on');
                                    drawnow
                                    
                                    instances = app.plotted("individual_ribs_centerlines");
                                    for j =1:length(instances)
                                        delete(instances(j));
                                    end
                                    app.plotted("individual_ribs_centerlines") = [];
    
                                    close(d);
                                end
                            end
                        end

                    case "Rib pairs"
                        if nodes_turned_on(i)
                            if app.pcrib_pairs~=0
                                d = uiprogressdlg(app.RibblesUIFigure,'Title','Plotting image...', 'Indeterminate','on');
                                drawnow
                                
                                hold(axes);
                                pcshow(app.pcrib_pairs, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                                app.plotted("rib_pairs") = [axes.Children(1)];
                                hold(axes)

                                close(d)
                            else
                                app.Tree.CheckedNodes = prevnodes;
                                uialert(app.RibblesUIFigure, 'Data not available!', 'Warning');
                            end
                        else
                            if isKey(app.plotted, "rib_pairs")
                                if ~isempty(app.plotted("rib_pairs"))
                                    d = uiprogressdlg(app.RibblesUIFigure,'Title','Removing image...', 'Indeterminate','on');
                                    drawnow
                                    
                                    instances = app.plotted("rib_pairs");
                                    for j =1:length(instances)
                                        delete(instances(j));
                                    end
                                    app.plotted("rib_pairs") = [];
    
                                    close(d);
                                end
                            end
                        end
                    
                    case "Rib pairs centerlines"
                        if nodes_turned_on(i)
                            if app.pcrib_pairs_centerlinesplot~=0
                                d = uiprogressdlg(app.RibblesUIFigure,'Title','Plotting image...', 'Indeterminate','on');
                                drawnow
                                
                                hold(axes);
                                pcshow(app.pcrib_pairs_centerlinesplot, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                                app.plotted("rib_pairs_centerlines") = [axes.Children(1)];
                                hold(axes)

                                close(d)
                            else
                                app.Tree.CheckedNodes = prevnodes;
                                uialert(app.RibblesUIFigure, 'Data not available!', 'Warning');
                            end
                        else
                            if isKey(app.plotted, "rib_pairs_centerlines")
                                if ~isempty(app.plotted("rib_pairs_centerlines"))
                                    d = uiprogressdlg(app.RibblesUIFigure,'Title','Removing image...', 'Indeterminate','on');
                                    drawnow
                                    
                                    instances = app.plotted("rib_pairs_centerlines");
                                    for j =1:length(instances)
                                        delete(instances(j));
                                    end
                                    app.plotted("rib_pairs_centerlines") = [];
    
                                    close(d);
                                end
                            end
                        end

                    case "Ribs deformation"
                        if nodes_turned_on(i)
                            if app.pcdeformation_ribs ~=0
                                d = uiprogressdlg(app.RibblesUIFigure,'Title','Plotting image...', 'Indeterminate','on');
                                drawnow
                                
                                hold(axes);
                                pcshow(app.pcdeformation_ribs, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                                app.plotted("deformation_ribs") = axes.Children(1);
                                hold(axes)

                                close(d)
                            else
                                app.Tree.CheckedNodes = prevnodes;
                                uialert(app.RibblesUIFigure, 'Data not available!', 'Warning');
                            end
                        else
                            if isKey(app.plotted, "deformation_ribs")
                                if ~isempty(app.plotted("deformation_ribs"))
                                    d = uiprogressdlg(app.RibblesUIFigure,'Title','Removing image...', 'Indeterminate','on');
                                    drawnow
                                    
                                    instances = app.plotted("deformation_ribs");
                                    for j =1:length(instances)
                                        delete(instances(j));
                                    end
                                    app.plotted("deformation_ribs") = [];
    
                                    close(d);
                                end
                            end
                        end
                    case "Ribs deformation centerlines"
                        if nodes_turned_on(i)
                            if app.pcdeformation_ribs_centerlinesplot ~=0
                                d = uiprogressdlg(app.RibblesUIFigure,'Title','Plotting image...', 'Indeterminate','on');
                                drawnow
                                
                                hold(axes);
                                pcshow(app.pcdeformation_ribs_centerlinesplot, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                                app.plotted("deformation_ribs_centerlines") = axes.Children(1);
                                hold(axes)

                                close(d)
                            else
                                app.Tree.CheckedNodes = prevnodes;
                                uialert(app.RibblesUIFigure, 'Data not available!', 'Warning');
                            end
                        else
                            if isKey(app.plotted, "deformation_ribs_centerlines")
                                if ~isempty(app.plotted("deformation_ribs_centerlines"))
                                    d = uiprogressdlg(app.RibblesUIFigure,'Title','Removing image...', 'Indeterminate','on');
                                    drawnow
                                    
                                    instances = app.plotted("deformation_ribs_centerlines");
                                    for j =1:length(instances)
                                        delete(instances(j));
                                    end
                                    app.plotted("deformation_ribs_centerlines") = [];
    
                                    close(d);
                                end
                            end
                        end                        
                end
                if isempty(app.Tree.CheckedNodes)
                    app.Image.Visible='on';
                    app.UIAxes_2.Visible='off';
                else
                    app.Image.Visible='off';
                    app.UIAxes_2.Visible='on';
                end
            end
            
        end

        % Callback function: Tree_2
        function Tree_2CheckedNodesChanged(app, event)
            node = app.Tree_2.SelectedNodes;
            prevnodes = event.PreviousCheckedNodes;
            nodes = [];
            nodes_turned_on = [];
            axes = app.UIAxes_2;

            if isempty(node.Children)
                nodes = [nodes node];
            else
                for i=1:length(node.Children)
                    nodes = [nodes node.Children(i)];
                end
            end

            for i=1:length(nodes)
                nodes_turned_on = [nodes_turned_on 0];
                if ~isempty(prevnodes)
                    turned_on = true;
                    for j=1:length(prevnodes)
                        if strcmp(prevnodes(j).Text, nodes(i).Text)
                            turned_on = false;
                            break
                        end
                    end
                    nodes_turned_on(i) = turned_on;
                else
                    if nodes_turned_on(i) == 0
                        nodes_turned_on(i) = true;
                    end
                end
            end
            
            for i=1:length(nodes)
                switch nodes(i).Text
                    case "Ribcage"
                        if nodes_turned_on(i)
                            if app.pcribcage2 ~=0
                                d = uiprogressdlg(app.RibblesUIFigure,'Title','Plotting image...', 'Indeterminate','on');
                                drawnow

                                hold(axes)
                                pcshow(app.pcribcage2, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                                app.plotted2("ribcage") = [axes.Children(1)];
                                hold(axes);    
                                
                                close(d)
                            else
                                app.Tree_2.CheckedNodes = prevnodes;
                                uialert(app.RibblesUIFigure, 'Data not available!', 'Warning');
                            end
                        else
                            if isKey(app.plotted2, "ribcage")
                                if ~isempty(app.plotted2("ribcage"))
                                    d = uiprogressdlg(app.RibblesUIFigure,'Title','Removing image...', 'Indeterminate','on');
                                    drawnow
                                    
                                    instances = app.plotted2("ribcage");
                                    for j =1:length(instances)
                                        delete(instances(j));
                                    end
                                    app.plotted2("ribcage") = [];
    
                                    close(d)
                                end
                            end
                        end

                    case "Spine"
                        if nodes_turned_on(i)
                            if app.pcspine2 ~=0
                                d = uiprogressdlg(app.RibblesUIFigure,'Title','Plotting image...', 'Indeterminate','on');
                                drawnow

                                hold(axes);
                                pcshow(app.pcspine2, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0])
                                app.plotted2("spine") = axes.Children(1); 
                                hold(axes);
                                
                                close(d);
                            else
                                app.Tree_2.CheckedNodes = prevnodes;
                                uialert(app.RibblesUIFigure, 'Data not available!', 'Warning');
                            end
                        else
                            if isKey(app.plotted2, "spine")
                                if ~isempty(app.plotted2("spine"))
                                    d = uiprogressdlg(app.RibblesUIFigure,'Title','Removing image...', 'Indeterminate','on');
                                    drawnow
                                    
                                    instances = app.plotted2("spine");
                                    for j =1:length(instances)
                                        delete(instances(j));
                                    end
                                    app.plotted2("spine") = [];
    
                                    close(d)
                                end
                            end
                        end

                    case "Spine centerline"
                        if nodes_turned_on(i)
                            if app.pcspinecenterline2 ~=0
                                d = uiprogressdlg(app.RibblesUIFigure,'Title','Plotting image...', 'Indeterminate','on');
                                drawnow
                                
                                hold(axes);
                                pcshow(app.pcspinecenterline2, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0])
                                app.plotted2("spinecenterline") = axes.Children(1); 
                                hold(axes);

                                close(d);
                            else
                                app.Tree_2.CheckedNodes = prevnodes;
                                uialert(app.RibblesUIFigure, 'Data not available!', 'Warning');
                            end
                        else
                            if isKey(app.plotted2, "spinecenterline")
                                if ~isempty(app.plotted2("spinecenterline"))
                                    d = uiprogressdlg(app.RibblesUIFigure,'Title','Removing image...', 'Indeterminate','on');
                                    drawnow
                                    
                                    instances = app.plotted2("spinecenterline");
                                    for j =1:length(instances)
                                        delete(instances(j));
                                    end
                                    app.plotted2("spinecenterline") = [];
    
                                    close(d);
                                end
                            end
                        end

                    case "Ribs"
                        if nodes_turned_on(i)
                            if app.pcribs2 ~=0
                                d = uiprogressdlg(app.RibblesUIFigure,'Title','Plotting image...', 'Indeterminate','on');
                                drawnow
                                
                                hold(axes);
                                pcshow(app.pcribs2, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0])
                                app.plotted2("ribs") = axes.Children(1);
                                hold(axes);

                                close(d)
                            else
                                app.Tree_2.CheckedNodes = prevnodes;
                                uialert(app.RibblesUIFigure, 'Data not available!', 'Warning');
                            end
                        else
                            if isKey(app.plotted2, "ribs")
                                if ~isempty(app.plotted2("ribs"))
                                    d = uiprogressdlg(app.RibblesUIFigure,'Title','Removing image...', 'Indeterminate','on');
                                    drawnow
                                    
                                    instances = app.plotted2("ribs");
                                    for j =1:length(instances)
                                        delete(instances(j));
                                    end
                                    app.plotted2("ribs") = [];
    
                                    close(d);
                                end
                            end
                        end
                     
                    case "Ribs segmented"
                        if nodes_turned_on(i)
                            if app.pcindividual_rib2 ~=0
                                d = uiprogressdlg(app.RibblesUIFigure,'Title','Plotting image...', 'Indeterminate','on');
                                drawnow
                                
                                hold(axes);
                                pcshow(app.pcindividual_ribs2, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                                app.plotted2("individual_ribs") = [axes.Children(1)];
                                hold(axes)

                                close(d)
                            else
                                app.Tree_2.CheckedNodes = prevnodes;
                                uialert(app.RibblesUIFigure, 'Data not available!', 'Warning');
                            end
                        else
                            if isKey(app.plotted2, "individual_ribs")
                                if ~isempty(app.plotted2("individual_ribs"))
                                    d = uiprogressdlg(app.RibblesUIFigure,'Title','Removing image...', 'Indeterminate','on');
                                    drawnow
                                    
                                    instances = app.plotted2("individual_ribs");
                                        for j =1:length(instances)
                                            delete(instances(j));
                                        end
                                    app.plotted2("individual_ribs") = [];
    
                                    close(d);
                                end
                            end
                        end
                    
                    case "Ribs segmented centerlines"
                        if nodes_turned_on(i)
                            if app.pcindividual_ribs_centerlinesplot2~=0
                                d = uiprogressdlg(app.RibblesUIFigure,'Title','Plotting image...', 'Indeterminate','on');
                                drawnow
                                
                                hold(axes);
                                pcshow(app.pcindividual_ribs_centerlinesplot2, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                                app.plotted2("individual_ribs_centerlines") = [axes.Children(1)];
                                hold(axes)

                                close(d)
                            else
                                app.Tree_2.CheckedNodes = prevnodes;
                                uialert(app.RibblesUIFigure, 'Data not available!', 'Warning');
                            end
                        else
                            if isKey(app.plotted2, "individual_ribs_centerlines")
                                if ~isempty(app.plotted2("individual_ribs_centerlines"))
                                    d = uiprogressdlg(app.RibblesUIFigure,'Title','Removing image...', 'Indeterminate','on');
                                    drawnow
                                    
                                    instances = app.plotted2("individual_ribs_centerlines");
                                    for j =1:length(instances)
                                        delete(instances(j));
                                    end
                                    app.plotted2("individual_ribs_centerlines") = [];
    
                                    close(d);
                                end
                            end
                        end

                    case "Rib pairs"
                        if nodes_turned_on(i)
                            if app.pcrib_pairs2~=0
                                d = uiprogressdlg(app.RibblesUIFigure,'Title','Plotting image...', 'Indeterminate','on');
                                drawnow
                                
                                hold(axes);
                                pcshow(app.pcrib_pairs2, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                                app.plotted2("rib_pairs") = [axes.Children(1)];
                                hold(axes)

                                close(d)
                            else
                                app.Tree_2.CheckedNodes = prevnodes;
                                uialert(app.RibblesUIFigure, 'Data not available!', 'Warning');
                            end
                        else
                            if isKey(app.plotted2, "rib_pairs")
                                if ~isempty(app.plotted2("rib_pairs"))
                                    d = uiprogressdlg(app.RibblesUIFigure,'Title','Removing image...', 'Indeterminate','on');
                                    drawnow
                                    
                                    instances = app.plotted2("rib_pairs");
                                    for j =1:length(instances)
                                        delete(instances(j));
                                    end
                                    app.plotted2("rib_pairs") = [];
    
                                    close(d);
                                end
                            end
                        end
                    
                    case "Rib pairs centerlines"
                        if nodes_turned_on(i)
                            if app.pcrib_pairs_centerlinesplot2~=0
                                d = uiprogressdlg(app.RibblesUIFigure,'Title','Plotting image...', 'Indeterminate','on');
                                drawnow
                                
                                hold(axes);
                                pcshow(app.pcrib_pairs_centerlinesplot2, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                                app.plotted2("rib_pairs_centerlines") = [axes.Children(1)];
                                hold(axes)

                                close(d)
                            else
                                app.Tree_2.CheckedNodes = prevnodes;
                                uialert(app.RibblesUIFigure, 'Data not available!', 'Warning');
                            end
                        else
                            if isKey(app.plotted2, "rib_pairs_centerlines")
                                if ~isempty(app.plotted2("rib_pairs_centerlines"))
                                    d = uiprogressdlg(app.RibblesUIFigure,'Title','Removing image...', 'Indeterminate','on');
                                    drawnow
                                    
                                    instances = app.plotted2("rib_pairs_centerlines");
                                    for j =1:length(instances)
                                        delete(instances(j));
                                    end
                                    app.plotted2("rib_pairs_centerlines") = [];
    
                                    close(d);
                                end
                            end
                        end

                    case "Ribs deformation"
                        if nodes_turned_on(i)
                            if app.pcdeformation_ribs2 ~=0
                                d = uiprogressdlg(app.RibblesUIFigure,'Title','Plotting image...', 'Indeterminate','on');
                                drawnow
                                
                                hold(axes);
                                pcshow(app.pcdeformation_ribs2, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                                app.plotted2("deformation_ribs") = axes.Children(1);
                                hold(axes)

                                close(d)
                            else
                                app.Tree_2.CheckedNodes = prevnodes;
                                uialert(app.RibblesUIFigure, 'Data not available!', 'Warning');
                            end
                        else
                            if isKey(app.plotted2, "deformation_ribs")
                                if ~isempty(app.plotted2("deformation_ribs"))
                                    d = uiprogressdlg(app.RibblesUIFigure,'Title','Removing image...', 'Indeterminate','on');
                                    drawnow
                                    
                                    instances = app.plotted2("deformation_ribs");
                                    for j =1:length(instances)
                                        delete(instances(j));
                                    end
                                    app.plotted2("deformation_ribs") = [];
    
                                    close(d);
                                end
                            end
                        end
                    case "Ribs deformation centerlines"
                        if nodes_turned_on(i)
                            if app.pcdeformation_ribs_centerlinesplot2 ~=0
                                d = uiprogressdlg(app.RibblesUIFigure,'Title','Plotting image...', 'Indeterminate','on');
                                drawnow
                                
                                hold(axes);
                                pcshow(app.pcdeformation_ribs_centerlinesplot2, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                                app.plotted2("deformation_ribs_centerlines") = axes.Children(1);
                                hold(axes)

                                close(d)
                            else
                                app.Tree_2.CheckedNodes = prevnodes;
                                uialert(app.RibblesUIFigure, 'Data not available!', 'Warning');
                            end
                        else
                            if isKey(app.plotted2, "deformation_ribs_centerlines")
                                if ~isempty(app.plotted2("deformation_ribs_centerlines"))
                                    d = uiprogressdlg(app.RibblesUIFigure,'Title','Removing image...', 'Indeterminate','on');
                                    drawnow
                                    
                                    instances = app.plotted2("deformation_ribs_centerlines");
                                    for j =1:length(instances)
                                        delete(instances(j));
                                    end
                                    app.plotted2("deformation_ribs_centerlines") = [];
    
                                    close(d);
                                end
                            end
                        end     
                end
                if isempty(app.Tree_2.CheckedNodes)
                    app.Image_2.Visible='on';
                    app.UIAxes_2.Visible='off';
                else
                    app.Image_2.Visible='off';
                    app.UIAxes_2.Visible='on';
                end
            end        
        end

        % Button pushed function: LoadImageButton_2
        function LoadImageButton_2Pushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [file, path] = uigetfile('*.nii', 'Select a file');
            delete(f);
            if ~isequal(file, 0)
                d = uiprogressdlg(app.RibblesUIFigure,'Title','Loading image...', 'Indeterminate','on');
                drawnow
                app.fullpathinput2 = string(fullfile(path, file));
                app.LoadImageButton_2.Text = file;

                axes = app.UIAxes_2;
                delete(axes.Children);
                app.plotted2 = containers.Map();
                app.pcspinecenterline2=0;
                app.pcspine2=0;
                app.pcribs2=0;
                app.ribs2=0;
                app.pcindividual_ribs2=0;
                app.pcindividual_ribs_centerlines2=[];
                app.pcindividual_ribs_centerlinesplot2=0;
                app.pcrib_pairs2=0;
                app.pcrib_pairs_centerlines2=[];
                app.pcrib_pairs_centerlinesplot2=0;
                app.pcdeformation_ribs2=0;
                app.pcdeformation_ribs_centerlines2=[];
                app.pcdeformation_ribs_centerlinesplot2=0;
                
                [app.pcribcage2, app.ribcage2] = get_ribcage(app.fullpathinput2, 5, 3, 1300, 1600, app.colorribcage);
                
                pcshow(app.pcribcage2, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                app.Image_2.Visible = 'off';
                app.UIAxes_2.Visible = 'on';
                app.plotted2("ribcage") = [axes.Children(1)];
                app.Tree_2.CheckedNodes = [app.RibcageNode_2];
                
                close(d);
            end
        end

        % Button pushed function: SaveImageButton_2
        function SaveImageButton_2Pushed(app, event)
            if app.done2 == 1
                f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
                [file, path] = uigetdir(app.fullpathinput2, 'Select a folder');
                delete(f);
                if isstring(path)
                    app.SaveImageButton_2.Text = path;
                        d = uiprogressdlg(app.RibblesUIFigure,'Title','Saving images...', 'Indeterminate','on');
                        drawnow
                        pcwrite(app.pcribage2, path + 'ribcage.ply');
                        pcwrite(app.pcribs2, path+ 'ribs.ply');
                        pcwrite(app.pcspine2, path+ 'spine.ply');
                        for i = 1:length(app.pcindividual_ribs2)
                            pcwrite(app.pcindividual_ribs2{i}, path+'rib_'+string(i)+'.ply');
                        end
                        for i = 1:length(app.pcrib_pairs2)
                            pcwrite(app.pcrib_pairs2{i,1}, path+'rib_'+string(i)+'_1.ply');
                            pcwrite(app.pcrib_pairs2{i,2}, path+'rib_'+string(i)+'_2.ply');
                        end
                        pcwrite(app.deformation_ribcage2, path + 'deformation_ribcage.ply');
                        close(d);
                else
                    uialert(app.RibblesUIFigure, 'Insert valid folder', 'Warning')
                end
            else
                uialert(app.RibblesUIFigure, 'Not done processing', 'Warning'); 
            end            
        end

        % Button pushed function: StartAlgorithmButton_2
        function StartAlgorithmButton_2Pushed(app, event)
            if isstring(app.fullpathinput2)
                d = uiprogressdlg(app.RibblesUIFigure,'Title','Seperating ribcage...', 'Indeterminate','on');
                drawnow
                [app.pcspinecenterline2, app.pcspine2, app.pcribs2, app.ribs2]=seperate_ribcage(app.ribcage2, app.colorspine, app.colorribs);
                axes=app.UIAxes_2;
                delete(axes.Children);
                app.plotted2 = containers.Map();
                hold(axes);
                pcshow(app.pcspine2, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]); 
                app.plotted2("spine") = [axes.Children(1)];
                pcshow(app.pcribs2, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                app.plotted2("ribs") = [axes.Children(1)];
                pcshow(app.pcspinecenterline2, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                app.plotted2("spinecenterline") = [axes.Children(1)];
                hold(axes);   
                app.Tree_2.CheckedNodes = [app.SpineNode_2, app.RibsNode_2, app.SpinecenterlineNode_2];
                close(d);

                d = uiprogressdlg(app.RibblesUIFigure,'Title','Seperating ribs...', 'Indeterminate','on');
                drawnow
                [app.pcindividual_ribs2, app.pcindividual_ribs_centerlines2]=seperate_ribs(app.ribs2, app.pcspinecenterline2, app.pcribs2);   
                delete(axes.Children);
                app.plotted2 = containers.Map();
                hold(axes);
                pcshow(app.pcspine2, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                app.plotted2("spine") = [axes.Children(1)];
                pcshow(app.pcspinecenterline2, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                app.plotted2("spinecenterline")=[axes.Children(1)];
                pcshow(app.pcindividual_ribs2, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                app.plotted2("individual_ribs") = [axes.Children(1)];
                app.plotted2("individual_ribs_centerlines") = [];
                app.pcindividual_ribs_centerlinesplot2 = pointCloud([0 0 0], 'Color', [0 0 0]);
                for i =1:length(app.pcindividual_ribs_centerlines2)
                    app.pcindividual_ribs_centerlinesplot2 = pcmerge(app.pcindividual_ribs_centerlinesplot2, app.pcindividual_ribs_centerlines2{i}, 1);
                end
                app.pcindividual_ribs_centerlinesplot2 = pointCloud(app.pcindividual_ribs_centerlinesplot2.Location(1:end, :), 'Color', app.pcindividual_ribs_centerlinesplot2.Color(1:end, :));
                pcshow(app.pcindividual_ribs_centerlinesplot2, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                app.plotted2("individual_ribs_centerlines") = [axes.Children(1)];
                hold(axes)
                app.Tree_2.CheckedNodes = [app.SpineNode_2, app.RibssegmentedNode_2, app.RibssegmentedcenterlinesNode_2, app.SpinecenterlineNode_2];
                close(d);
                               
                d = uiprogressdlg(app.RibblesUIFigure,'Title','Finding rib pairs...', 'Indeterminate','on');
                drawnow
                [app.pcrib_pairs2, app.pcrib_pairs_centerlines2] = find_rib_pairs(app.pcindividual_ribs_centerlines2, app.pcspinecenterline2, app.pcribs2);  
                delete(axes.Children);
                app.plotted2=containers.Map();
                hold(axes);
                pcshow(app.pcspine2, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                app.plotted2("spine") = [axes.Children(1)];
                pcshow(app.pcspinecenterline2, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                app.plotted2("spinecenterline")=[axes.Children(1)];
                pcshow(app.pcrib_pairs2, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                app.plotted2("rib_pairs") = [axes.Children(1)];
                app.plotted2("rib_pairs_centerlines") = [];
                app.pcrib_pairs_centerlinesplot2 = pointCloud([0 0 0], 'Color', [0 0 0]);
                for i =1:length(app.pcrib_pairs_centerlines2)
                    app.pcrib_pairs_centerlinesplot2 = pcmerge(app.pcrib_pairs_centerlinesplot2, app.pcrib_pairs_centerlines2{i, 1}, 1);
                    app.pcrib_pairs_centerlinesplot2 = pcmerge(app.pcrib_pairs_centerlinesplot2, app.pcrib_pairs_centerlines2{i, 2}, 1);
                end
                app.pcrib_pairs_centerlinesplot2 = pointCloud(app.pcrib_pairs_centerlinesplot2.Location(1:end, :), 'Color', app.pcrib_pairs_centerlinesplot2.Color(1:end, :));
                pcshow(app.pcrib_pairs_centerlinesplot2, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                app.plotted2("rib_pairs_centerlines") = [axes.Children(1)];
                hold(axes)
                app.Tree_2.CheckedNodes = [app.SpineNode_2, app.RibpairsNode_2, app.RibpairscenterlinesNode_2, app.SpinecenterlineNode_2];
                close(d);

                d = uiprogressdlg(app.RibblesUIFigure,'Title','Calculating deformity of ribs...', 'Indeterminate','on');
                drawnow
                [app.pcdeformation_ribs2, app.pcdeformation_ribs_centerlines2] = calculate_deformity(app.pcrib_pairs_centerlines2, app.pcribs2);
                delete(axes.Children);
                app.plotted2=containers.Map();
                hold(axes);
                pcshow(app.pcspine2, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                app.plotted2("spine") = [axes.Children(1)];
                pcshow(app.pcdeformation_ribs2, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                app.plotted2("deformation_ribs") = [axes.Children(1)];
                app.pcdeformation_ribs_centerlinesplot2 = pointCloud([0 0 0], 'Color', [0 0 0]);
                for i =1:length(app.pcdeformation_ribs_centerlines2)
                    app.pcdeformation_ribs_centerlinesplot2 = pcmerge(app.pcdeformation_ribs_centerlinesplot2, app.pcdeformation_ribs_centerlines2{i, 1}, 1);
                    app.pcdeformation_ribs_centerlinesplot2 = pcmerge(app.pcdeformation_ribs_centerlinesplot2, app.pcdeformation_ribs_centerlines2{i, 2}, 1);
                end
                app.pcdeformation_ribs_centerlinesplot2 = pointCloud(app.pcdeformation_ribs_centerlinesplot2.Location(1:end, :), 'Color', app.pcdeformation_ribs_centerlinesplot2.Color(1:end, :));
                pcshow(app.pcdeformation_ribs_centerlinesplot2, 'Parent', axes, 'AxesVisibility', 'on', 'BackgroundColor', [0 0 0]);
                app.plotted2("deformation_ribs_centerlines") = [axes.Children(1)];
                hold(axes)
                app.Tree_2.CheckedNodes = [app.SpineNode_2, app.DeformationRibcageNode_2, app.RibsdeformationNode_2, app.RibsdeformationcenterlinesNode_2];
                close(d);
                
                app.done = 1;
            else
                uialert(app.RibblesUIFigure, 'First load an image!','Warning');
            end            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.RibblesUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {703, 703};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {628, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.RibblesUIFigure = uifigure('Visible', 'off');
            app.RibblesUIFigure.Name = 'Ribbles';
            app.RibblesUIFigure.Icon = fullfile(pathToMLAPP, 'ribblesIcon.png');
            app.RibblesUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.RibblesUIFigure.Tag = '1.0.0';
            app.GridLayout = uigridlayout(app.RibblesUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.LeftPanel);
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.LoadImageButton = uibutton(app.GridLayout7, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.SaveImageButton = uibutton(app.GridLayout7, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.StartAlgorithmButton = uibutton(app.GridLayout5, 'push');
            app.StartAlgorithmButton.ButtonPushedFcn = createCallbackFcn(app, @StartAlgorithmButtonPushed, true);
            app.StartAlgorithmButton.Text = 'Start Algorithm';
            app.PreOperativeLabel = uilabel(app.GridLayout5);
            app.PreOperativeLabel.Text = 'Pre-Operative';
            app.GridLayout9 = uigridlayout(app.GridLayout5);
            app.UIAxes = uiaxes(app.GridLayout9);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Tree = uitree(app.GridLayout9, 'checkbox');
            app.RibcageNode = uitreenode(app.Tree);
            app.RibcageNode.Text = 'Ribcage';
            app.SplittedRibcageNode = uitreenode(app.Tree);
            app.SplittedRibcageNode.Text = 'Splitted Ribcage';
            app.SpineNode = uitreenode(app.SplittedRibcageNode);
            app.SpineNode.Text = 'Spine';
            app.SpinecenterlineNode = uitreenode(app.SplittedRibcageNode);
            app.SpinecenterlineNode.Text = 'Spine centerline';
            app.RibsNode = uitreenode(app.SplittedRibcageNode);
            app.RibsNode.Text = 'Ribs';
            app.SegmentedribsNode = uitreenode(app.Tree);
            app.SegmentedribsNode.Text = 'Segmented ribs';
            app.RibssegmentedNode = uitreenode(app.SegmentedribsNode);
            app.RibssegmentedNode.Text = 'Ribs segmented';
            app.RibssegmentedcenterlinesNode = uitreenode(app.SegmentedribsNode);
            app.RibssegmentedcenterlinesNode.Text = 'Ribs segmented centerlines';
            app.FoundribpairsNode = uitreenode(app.Tree);
            app.FoundribpairsNode.Text = 'Found rib pairs';
            app.RibpairsNode = uitreenode(app.FoundribpairsNode);
            app.RibpairsNode.Text = 'Rib pairs';
            app.RibpairscenterlinesNode = uitreenode(app.FoundribpairsNode);
            app.RibpairscenterlinesNode.Text = 'Rib pairs centerlines';
            app.DeformationRibcageNode = uitreenode(app.Tree);
            app.DeformationRibcageNode.Text = 'Deformation Ribcage';
            app.RibsdeformationNode = uitreenode(app.DeformationRibcageNode);
            app.RibsdeformationNode.Text = 'Ribs deformation';
            app.RibsdeformationcenterlinesNode = uitreenode(app.DeformationRibcageNode);
            app.RibsdeformationcenterlinesNode.Text = 'Ribs deformation centerlines';
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.Image = uiimage(app.GridLayout9);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'ribblesLogo.png');
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.PostOperativeLabel = uilabel(app.GridLayout3);
            app.PostOperativeLabel.Text = 'Post-Operative';
            app.StartAlgorithmButton_2 = uibutton(app.GridLayout3, 'push');
            app.StartAlgorithmButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartAlgorithmButton_2Pushed, true);
            app.StartAlgorithmButton_2.Text = 'Start Algorithm';
            app.GridLayout8 = uigridlayout(app.GridLayout3);
            app.LoadImageButton_2 = uibutton(app.GridLayout8, 'push');
            app.LoadImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButton_2Pushed, true);
            app.LoadImageButton_2.Text = 'Load Image';
            app.SaveImageButton_2 = uibutton(app.GridLayout8, 'push');
            app.SaveImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButton_2Pushed, true);
            app.SaveImageButton_2.Text = 'Save Image';
            app.GridLayout9_2 = uigridlayout(app.GridLayout3);
            app.UIAxes_2 = uiaxes(app.GridLayout9_2);
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.Tree_2 = uitree(app.GridLayout9_2, 'checkbox');
            app.RibcageNode_2 = uitreenode(app.Tree_2);
            app.RibcageNode_2.Text = 'Ribcage';
            app.SplittedRibcageNode_2 = uitreenode(app.Tree_2);
            app.SplittedRibcageNode_2.Text = 'Splitted Ribcage';
            app.SpineNode_2 = uitreenode(app.SplittedRibcageNode_2);
            app.SpineNode_2.Text = 'Spine';
            app.SpinecenterlineNode_2 = uitreenode(app.SplittedRibcageNode_2);
            app.SpinecenterlineNode_2.Text = 'Spine centerline';
            app.RibsNode_2 = uitreenode(app.SplittedRibcageNode_2);
            app.RibsNode_2.Text = 'Ribs';
            app.SegmentedribsNode_2 = uitreenode(app.Tree_2);
            app.SegmentedribsNode_2.Text = 'Segmented ribs';
            app.RibssegmentedNode_2 = uitreenode(app.SegmentedribsNode_2);
            app.RibssegmentedNode_2.Text = 'Ribs segmented';
            app.RibssegmentedcenterlinesNode_2 = uitreenode(app.SegmentedribsNode_2);
            app.RibssegmentedcenterlinesNode_2.Text = 'Ribs segmented centerlines';
            app.FoundribpairsNode_2 = uitreenode(app.Tree_2);
            app.FoundribpairsNode_2.Text = 'Found rib pairs';
            app.RibpairsNode_2 = uitreenode(app.FoundribpairsNode_2);
            app.RibpairsNode_2.Text = 'Rib pairs';
            app.RibpairscenterlinesNode_2 = uitreenode(app.FoundribpairsNode_2);
            app.RibpairscenterlinesNode_2.Text = 'Rib pairs centerlines';
            app.DeformationRibcageNode_2 = uitreenode(app.Tree_2);
            app.DeformationRibcageNode_2.Text = 'Deformation Ribcage';
            app.RibsdeformationNode_2 = uitreenode(app.DeformationRibcageNode_2);
            app.RibsdeformationNode_2.Text = 'Ribs deformation';
            app.RibsdeformationcenterlinesNode_2 = uitreenode(app.DeformationRibcageNode_2);
            app.RibsdeformationcenterlinesNode_2.Text = 'Ribs deformation centerlines';
            % Assign Checked Nodes
            app.Tree_2.CheckedNodesChangedFcn = createCallbackFcn(app, @Tree_2CheckedNodesChanged, true);
            app.Image_2 = uiimage(app.GridLayout9_2);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'ribblesLogo.png');
            % Show the figure after all components are created
            app.RibblesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ribbles

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RibblesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RibblesUIFigure)
        end
    end
end