classdef ScolioBolio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        GridLayout5             matlab.ui.container.GridLayout
        GridLayout6             matlab.ui.container.GridLayout
        StartAlgorithmButton    matlab.ui.control.Button
        SaveImageButton         matlab.ui.control.Button
        LoadImageButton         matlab.ui.control.Button
        TabGroup2               matlab.ui.container.TabGroup
        RibPairsTab             matlab.ui.container.Tab
        PreOperativeLabel       matlab.ui.control.Label
        SeperatedRibsTab        matlab.ui.container.Tab
        SeperatedRibcageTab     matlab.ui.container.Tab
        RibsDeformationTab      matlab.ui.container.Tab
        RibcageTab              matlab.ui.container.Tab
        RightPanel              matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        GridLayout4             matlab.ui.container.GridLayout
        StartAlgorithmButton_2  matlab.ui.control.Button
        SaveImageButton_2       matlab.ui.control.Button
        LoadImageButton_2       matlab.ui.control.Button
        TabGroup                matlab.ui.container.TabGroup
        RibPairsTab_2           matlab.ui.container.Tab
        PostOperativeLabel      matlab.ui.control.Label
        SeperatedRibsTab_2      matlab.ui.container.Tab
        SeperatedRibcageTab_2   matlab.ui.container.Tab
        RibcageTab_2            matlab.ui.container.Tab
        RibsDeformationTab_2    matlab.ui.container.Tab
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        colorribcage=[255,255,255]
        colorspine=[0,255,0]
        colorribs=[0,0,255]
        
        fullpathinput=0
        fullpathoutput=0
        ribcage
        pcribcage
        pcspinecenterline
        spinecenterline
        pcspine
        spine
        pcribs
        ribs
        pcindividual_ribs
        individual_ribs
        pcrib_pairs
        rib_pairs
        done
    end
    
    methods (Access = private)      
        function myUpdateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {'1x', currentFigureWidth/2};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.WindowState = 'maximized';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @myUpdateAppLayout, true);
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [file, path] = uigetfile('*.*', 'Select a file');
            delete(f);
            if ~isequal(file, 0)
                app.fullpathinput = string(fullfile(path, file));
                app.LoadImageButton.Text = file;
                
                d = uiprogressdlg(app.UIFigure,'Title','Loading image...', 'Indeterminate','on');
                drawnow
                
                [app.pcribcage, app.ribcage] = get_ribcage(app.fullpathinput, 5, 3, 1350, app.colorribcage);
                pcshow(app.pcribcage); 

                close(d);
            end
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [file, path] = uiputfile('*.*', 'Select a folder');
            delete(f);
            app.fullpathoutput = string(fullfile(path, file));
            if isstring(path)
                app.SaveImageButton.Text = path;
                if app.done == 1
                    d = uiprogressdlg(app.UIFigure,'Title','Saving images...', 'Indeterminate','on');
                    drawnow
                    pcwrite(app.pcribage, path + 'ribcage.ply');
                    pcwrite(app.pcribs, path+ 'ribs.ply');
                    pcwrite(app.pcspine, path+ 'spine.ply');
                    for i = 1:length(app.pcindividual_ribs)
                        pcwrite(app.pcindividual_ribs{i}, path+'rib_'+string(i)+'.ply');
                    end
                    for i = 1:length(app.pcrib_pairs)
                        pcwrite(app.pcrib_pairs{i,1}, path+'rib_'+string(i)+'_1.ply');
                        pcwrite(app.pcrib_pairs{i,2}, path+'rib_'+string(i)+'_2.ply');
                    end
                    close(d);
                else
                    warndlg('Not done processing','Warning'); 
                end
           else
           end
        end

        % Button pushed function: StartAlgorithmButton
        function StartAlgorithmButtonPushed(app, event)
            if isstring(app.fullpathinput)
                d = uiprogressdlg(app.UIFigure,'Title','Seperating ribcage...', 'Indeterminate','on');
                drawnow
                [app.pcspinecenterline, app.spinecenterline, app.pcspine, app.spine, app.pcribs, app.ribs]=seperate_ribcage(app.ribcage, app.colorspine, app.colorribs);
                figure; hold on;
                pcshow(app.pcspinecenterline);
                pcshow(app.pcspine);
                pcshow(app.pcribs);
                hold off;
                close(d);
                
                d = uiprogressdlg(app.UIFigure,'Title','Seperating ribs...', 'Indeterminate','on');
                drawnow
                [app.pcindividual_ribs, app.individual_ribs]=seperate_ribs(app.ribs, app.pcspinecenterline);                
                figure; hold on;
                pcshow(app.pcspinecenterline);
                pcshow(app.pcspine);
                pcshow(app.pcribs)
                for i =1:length(app.pcindividual_ribs)
                    pcshow(app.pcindividual_ribs{i});
                end
                hold off;
                close(d);

                d = uiprogressdlg(app.UIFigure,'Title','Finding rib pairs...', 'Indeterminate','on');
                drawnow
                [app.pcrib_pairs, app.rib_pairs] = find_rib_pairs(app.pcindividual_ribs, app.individual_ribs, app.pcspinecenterline);         
                figure; hold on;
                pcshow(app.pcspinecenterline);
                pcshow(app.spine);
                for i = 1:length(app.pcrib_pairs)
                    pcshow(app.pcrib_pairs{i,1});
                    pcshow(app.pcrib_pairs{i,2});
                end
                hold off;
                close(d);

                app.done = 1;
               
            else
                warndlg('First load an image!','Warning');
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {448, 448};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {367, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.LeftPanel);
            app.PreOperativeLabel = uilabel(app.GridLayout5);
            app.PreOperativeLabel.Text = 'Pre-Operative';
            app.TabGroup2 = uitabgroup(app.GridLayout5);
            app.RibcageTab = uitab(app.TabGroup2);
            app.RibcageTab.Title = 'Ribcage';
            app.RibsDeformationTab = uitab(app.TabGroup2);
            app.RibsDeformationTab.Title = 'Ribs Deformation';
            app.SeperatedRibcageTab = uitab(app.TabGroup2);
            app.SeperatedRibcageTab.Title = 'Seperated Ribcage';
            app.SeperatedRibsTab = uitab(app.TabGroup2);
            app.SeperatedRibsTab.Title = 'Seperated Ribs';
            app.RibPairsTab = uitab(app.TabGroup2);
            app.RibPairsTab.Title = 'Rib Pairs';
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.LoadImageButton = uibutton(app.GridLayout6, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.SaveImageButton = uibutton(app.GridLayout6, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.StartAlgorithmButton = uibutton(app.GridLayout6, 'push');
            app.StartAlgorithmButton.ButtonPushedFcn = createCallbackFcn(app, @StartAlgorithmButtonPushed, true);
            app.StartAlgorithmButton.Text = 'Start Algorithm';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.PostOperativeLabel = uilabel(app.GridLayout3);
            app.PostOperativeLabel.Text = 'Post-Operative';
            app.TabGroup = uitabgroup(app.GridLayout3);
            app.RibsDeformationTab_2 = uitab(app.TabGroup);
            app.RibsDeformationTab_2.Title = 'Ribs Deformation';
            app.RibcageTab_2 = uitab(app.TabGroup);
            app.RibcageTab_2.Title = 'Ribcage';
            app.SeperatedRibcageTab_2 = uitab(app.TabGroup);
            app.SeperatedRibcageTab_2.Title = 'Seperated Ribcage';
            app.SeperatedRibsTab_2 = uitab(app.TabGroup);
            app.SeperatedRibsTab_2.Title = 'Seperated Ribs';
            app.RibPairsTab_2 = uitab(app.TabGroup);
            app.RibPairsTab_2.Title = 'Rib Pairs';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.LoadImageButton_2 = uibutton(app.GridLayout4, 'push');
            app.LoadImageButton_2.Text = 'Load Image';
            app.SaveImageButton_2 = uibutton(app.GridLayout4, 'push');
            app.SaveImageButton_2.Text = 'Save Image';
            app.StartAlgorithmButton_2 = uibutton(app.GridLayout4, 'push');
            app.StartAlgorithmButton_2.Text = 'Start Algorithm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ScolioBolio

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end